classdef proyeccion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        ResetButton                  matlab.ui.control.Button
        ProyeccinEstereogrficaLabel  matlab.ui.control.Label
    end


    methods (Access = private)
        
        function makeSphere(app)
            
            %Creacion esfera
            [X,Y,Z] = sphere(100)
        
            
            obj = surf(app.UIAxes2, X,Y,Z,'EdgeColor','none');
            set(obj,'FaceAlpha',0.1);
            hold(app.UIAxes2, "on")
            
            %Creacion circulo unitario
            angle = 0:0.01:2*pi
            a = cos(angle);
            b = sin(angle);
            c = 0.*angle;
            plot3(app.UIAxes2,a,b,c,':','Color','#606060');
           
           
            %Limites de la grafica
            xlim(app.UIAxes,[-5,5])
            ylim(app.UIAxes,[-5,5])
            
        end
        
        function makeCircle(app)
            %Creacion del circulo unitario en el plano complejo
            angle = 0:0.01:2*pi;
            a = cos(angle);
            b = sin(angle);
            
            %Graficar el circulo unitario
            plot(app.UIAxes,a,b,':','Color','#606060');
            hold(app.UIAxes, "on")
            
        end
        
        function [x, y] = selectDataPoints(~, ax)
        
            roi = drawpoint(ax,'Visible',"off"); %Obtener punto donde se clickeo
        
            x = roi.Position(1); %Coordenada x del click
        
            y = roi.Position(2); %Coordenada y del click
        
        end
        
        function init(app) %Funcion para iniciar el proceso de la app
            
                
                %Limites del plano complejo
                xlim(app.UIAxes,[-5,5])
                ylim(app.UIAxes,[-5,5])
    
                %Punto inicial dado por el usuario
                [x1,y1] = selectDataPoints(app, app.UIAxes)

            while true
                
                xlim(app.UIAxes,[-5,5])
                ylim(app.UIAxes,[-5,5])
                
                %Siguiente punto dado por el usuario
                [x2,y2] = selectDataPoints(app, app.UIAxes)
        
                
                %Creacion de intervalos [x1,x2] y [y1,y2]
                x = linspace(x1,x2)
                y = linspace(y1,y2)
                
                %Graficar en el plano complejo
                plot(app.UIAxes, x,y,'Color', 'b')

                %Proyeccion del intervalo en la esfera
                [xe,ye,ze] = Diag2Esfera(x,y)
                
                %Graficar en la esfera
                plot3(app.UIAxes2,xe,ye,ze,'Color', 'b')
  
                %Reemplazar variables para el proximo punto
                x1 = x2;
                y1 = y2;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            %Creacion de la esfera
            makeSphere(app)
            
            %Creacion circulo unitario
            makeCircle(app)
            init(app)
            
              
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            %Limpiar el plano complejo y la esfera
            app.UIAxes.cla
            app.UIAxes2.cla
            
            %Volver a crear la esfera y el circulo
            makeSphere(app)
            makeCircle(app)
            
            %Iniciar de nuevo la funcionalidad de la app
            init(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plano Complejo')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, {'Esfera de Riemann'; ''})
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = {'Reset'; ''};
            app.ProyeccinEstereogrficaLabel = uilabel(app.UIFigure);
            app.ProyeccinEstereogrficaLabel.Text = 'Proyección Estereográfica';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proyeccion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end